classdef control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        APPUIFigure          matlab.ui.Figure
        fight_type           matlab.ui.control.DropDown
        Label_9              matlab.ui.control.Label
        simulate_result      matlab.ui.control.TextArea
        formal_num2          matlab.ui.control.Label
        formal_num1          matlab.ui.control.Label
        formal_num           matlab.ui.control.Label
        net_loss_rate2       matlab.ui.control.Label
        net_loss_rate1       matlab.ui.control.Label
        net_loss_rate        matlab.ui.control.Label
        maker_information    matlab.ui.control.TextArea
        fight_type_label     matlab.ui.control.Label
        result               matlab.ui.control.TextArea
        report               matlab.ui.control.TextArea
        create_simulate      matlab.ui.control.Button
        review               matlab.ui.control.TextArea
        reinforce_rate2      matlab.ui.control.Label
        fight_loss_rate2     matlab.ui.control.Label
        current_solder2      matlab.ui.control.Label
        reinforce_rate1      matlab.ui.control.Label
        fight_loss_rate1     matlab.ui.control.Label
        current_solder1      matlab.ui.control.Label
        sLabel_2             matlab.ui.control.Label
        Label_8              matlab.ui.control.Label
        Label_7              matlab.ui.control.Label
        sLabel               matlab.ui.control.Label
        Label_5              matlab.ui.control.Label
        TextArea_2           matlab.ui.control.TextArea
        TextArea             matlab.ui.control.TextArea
        Image2               matlab.ui.control.Image
        Image                matlab.ui.control.Image
        TabGroup             matlab.ui.container.TabGroup
        Tab                  matlab.ui.container.Tab
        S_x                  matlab.ui.control.NumericEditField
        m2Label              matlab.ui.control.Label
        p_x                  matlab.ui.control.NumericEditField
        rmp_xEditFieldLabel  matlab.ui.control.Label
        r_x                  matlab.ui.control.NumericEditField
        r_xLabel             matlab.ui.control.Label
        alpha                matlab.ui.control.NumericEditField
        alphaLabel           matlab.ui.control.Label
        x                    matlab.ui.control.NumericEditField
        xLabel               matlab.ui.control.Label
        Tab_2                matlab.ui.container.Tab
        p_y                  matlab.ui.control.NumericEditField
        rmp_yLabel           matlab.ui.control.Label
        r_y                  matlab.ui.control.NumericEditField
        r_xLabel_2           matlab.ui.control.Label
        y                    matlab.ui.control.NumericEditField
        xLabel_2             matlab.ui.control.Label
        S_y                  matlab.ui.control.NumericEditField
        m2Label_2            matlab.ui.control.Label
        beta                 matlab.ui.control.NumericEditField
        alphaLabel_2         matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        Label                matlab.ui.control.Label
        mixed_fight          matlab.ui.control.UIAxes
        guerralli_fight      matlab.ui.control.UIAxes
        formal_fight         matlab.ui.control.UIAxes
    end

%****************************************************************    
    properties (Access = private)
        datetimer         % Description
        simulate          % 模拟界面
        out_interface     % 输出界面
        init_object1      % 蓝方初始化结构体 
        init_object2      % 红方初始化结构体
        fight_loss1       % 蓝方累计减员
        fight_loss2       % 红方累计减员
        add_num1          % 蓝方累计增援
        add_num2          % 红方累计增援
    end

    properties (Access = public)
        Property2 % Description
    end
 %****************************************************************     
    methods (Access = public)
        function datetimer_handler(app)
            % 函数说明：创建时间句柄

            CT = clock; % Current Time 当前时间
            set(app.Label_2,"Text",['当前时间:',...
            num2str(CT(1)),'年',num2str(CT(2),"%02d"),'月',num2str(CT(3),"%02d"),'日 ',...
            num2str(CT(4),"%02d"),': ',num2str(CT(5),"%02d"),': ',num2str(CT(6),"%02.f")]);
        end
%*****************************************************************
        function update_dynamic(app,step)
            % 函数说明：每一轮更新战斗参数
            % 参数说明：step:仿真加速倍数
            global object1 object2;
            % 显示双方当前兵力
            app.current_solder1.Text =num2str(round(object1.x));  app.current_solder2.Text=num2str(round(object2.y));
            % 显示双方当前战斗减员率
            app.fight_loss_rate1.Text=num2str(object1.f,'%.2f');  app.fight_loss_rate2.Text=num2str(object2.g,'%.2f');
            % 显示双方当前增援率
            app.reinforce_rate1.Text =num2str(object1.u,'%.2f');  app.reinforce_rate2.Text=num2str(object2.v,'%.2f');
            % 显示双方净减员率
            app.net_loss_rate1.Text  =num2str(object1.F,'%.2f');  app.net_loss_rate2.Text=num2str(object2.G,'%.2f');
            % 混合战显示双方游击部队剩余人数
            app.formal_num1.Text=num2str(object1.x2,'%.f');     app.formal_num2.Text=num2str(object2.y2,'%.f'); 
            
            % 简单分析战场情况
            range = [0,0.1,0.25,0.5,2,4,10,inf];
            label = {'蓝方快赢了!', '蓝方占据压倒性优势!', '蓝方优势明显!', '双方实力相当!',...
                '红方优势明显!','红方占据压倒性优势!','红方快赢了!'};
            h= object2.y/object1.x;                 
            app.review.Value=[label{find(h > range, 1, 'last')};
            app.review.Value];
            
            % 累计阵亡和增援人数统计
            app.fight_loss1=app.fight_loss1+object1.f*step;
            app.fight_loss2=app.fight_loss2+object2.g*step;
            app.add_num1=app.add_num1+object1.u*step;
            app.add_num2=app.add_num2+object2.v*step;
            
        end  
%*****************************************************************

        function update_legend(app,fight_type,t)
            % 函数说明：每一轮模拟更新界面与重新置位参数
            % 参数说明：fight_type:战斗类型
            %           t:仿真时间

            global object1 object2
            % 播报胜者
            if object1.x>object2.y
                [y,fs] = audioread('figures\蓝军获胜.mp3');
                sound(y,fs);
                victor='蓝军';
                app.result.Value='蓝军胜';
                app.result.FontColor='0,0,1';
            
            elseif object1.x==object2.y
                [y,fs] = audioread('figures\双方战平.mp3');
                sound(y,fs);
                victor='战平';
                app.result.Value='双方战平';
                app.result.FontColor='0,0,0';
            else
                [y,fs] = audioread('figures\红军获胜.mp3');
                sound(y,fs);
                victor='红军';
                app.result.Value='红军胜';
                app.result.FontColor='1,0,0';                        
            end
            
            % 判断战斗类型,更新界面
            switch fight_type
                    
                case '正规战'
                    app.fight_type_label.Text='正规战结果';
                    %app.create_simulate.Text='游击战模拟中';
                    
                case '游击战'
                    app.fight_type_label.Text='游击战结果';                    
                    %app.create_simulate.Text='混合战Ⅰ模拟中';
                    
                case '混合战Ⅰ'
                    app.fight_type_label.Text='混合战Ⅰ结果';
                    
                case '混合战Ⅱ'
                    app.create_simulate.Text='模拟结束';pause(4);                    
                    [y,fs] = audioread('figures\输出结果.mp3');
                    sound(y,fs);
                    pause(5);
            end
            % 每次模拟播报双方战斗情况
            app.report.Value=[
                             cellstr(['获胜方：',victor,'   ','仿真时间：',num2str(t*0.1,'%.1f'),'s']) ; 
                             cellstr(['蓝军阵亡总人数：',num2str(app.fight_loss1*0.1,'%.f'),'人','          '  ...
                             '  ','红军阵亡总人数：',num2str(app.fight_loss2*0.1,'%.f'),'人']);
                             cellstr(['蓝军平均阵亡人数：',num2str(app.fight_loss1/t,'%.1f'),'人/s','  '...
                             '   ','红军平均阵亡人数：',num2str(app.fight_loss2/t,'%.1f'),'人/s']);
                             cellstr(['蓝军平均增援率：', num2str(app.add_num1/t,'%.1f'),'人/s','       '  ...
                             '   ','红军平均增援率：',num2str(app.add_num2/t,'%.1f'),'人/s',])];
            

            %重新置位统计变量
            app.fight_loss1=0; app.fight_loss2=0;
            app.add_num1=0;    app.add_num2=0;
            app.fight_type.Enable='on';
            
        end
%*****************************************************************                
        function output(app,h1,h2,fight_type)
        % 函数说明：将模拟界面中的人数变化图绘入当前控制界面
        % 参数说明：h1:蓝方人数变化曲线图变量
        %           h2:红方人数变化曲线图变量
        %           fight_type: 战斗模式
            switch fight_type
                case '正规战'
                    % 正规战界面
                    cla(app.formal_fight);
                    copyobj([h1,h2],app.formal_fight);
                    app.formal_fight.Visible='on';
                    
                case '游击战'
                    % 游击战界面
                    cla(app.guerralli_fight);
                    app.guerralli_fight.Visible='on';
                    copyobj([h1,h2],app.guerralli_fight);              
                    
                case '混合战Ⅰ'
                    % 混合战1界面
                    cla(app.mixed_fight);
                    app.mixed_fight.Visible='on';
                    copyobj([h1,h2],app.mixed_fight);
                    
                case '混合战Ⅱ' 
                    app.out_interface=output_interface(app,h1,h2);
      
            end
            if length(app.mixed_fight.Children)~=0
                app.simulate_result.Visible='off';
                
            elseif length(app.guerralli_fight.Children)
                app.simulate_result.Position=[525,64,170,54];
                app.simulate_result.FontSize=30;
            elseif length(app.formal_fight.Children)
                app.simulate_result.Position=[355,44,343,94];
                app.simulate_result.FontSize=50;
            end
        end
%*****************************************************************           
        function restore_parameter(app)
        % 函数说明：每次仿真之前，将仿真对象参数修正为当前初始栏中的值，方便修改
            global object1 object2
            object1.x=app.x.Value;         object2.y=app.y.Value;
            object1.r_x=app.r_x.Value;     object2.r_y=app.r_y.Value;
            object1.p_x=app.p_x.Value;     object2.p_y=app.p_y.Value;
            object1.alpha=app.alpha.Value; object2.beta=app.beta.Value;
            object1.S_x=app.S_x.Value;     object2.S_y=app.S_y.Value;
            object1.fight_type=app.fight_type.Value;
            object2.fight_type=app.fight_type.Value;
            object2.v=0;                   object2.g=0;            
            object1.u=0;                   object1.f=0;
            object1.x0=object1.x;          object2.y0=object2.y;
            object1.k1=0.5;                object2.k2=0.5;       %混合战中双方正规部队人数比例
            object1.x1=object1.x0*object1.k1;      
            object2.y1=object2.y0*object2.k2;
            object1.x2=object1.x0*(1-object1.k1);  
            object2.y2=object2.y0*(1-object2.k2);
            object1.F=0;                   object2.G=0;
        end
    end
%*****************************************************************   
    methods (Access = private)

        function init_interface(app) 
            % 函数说明：初始化控制台界面
            app.formal_num.Visible='off';
            app.formal_num1.Visible='off';
            app.formal_num2.Visible='off';
            
            % 添加时间控件
            % 删除现存的定时器变量。这个函数也可以用来关闭定时器
            delete(timerfind);
            % 配置并开启显示时间的定时器
            app.datetimer = timer;
            % 开启定时器代码运行后，延时1s再调用回调函数1
            app.datetimer.StartDelay = 1;
            % 定时器每隔1s调用一次回调函数
            app.datetimer.Period = 1;
            % 定时器的工作模式，读者可参考官方文档查阅不同模式的区别。此模式下，定时器会循环运行
            app.datetimer.ExecutionMode = 'fixedRate';
            app.datetimer.BusyMode = 'queue';
            app.datetimer.TimerFcn = @(~,~) app.datetimer_handler;
            start(app.datetimer);
            
            % 显示初始化值
            app.x.Value=app.init_object1.x;         app.y.Value=app.init_object2.y;
            app.alpha.Value=app.init_object1.alpha; app.beta.Value=app.init_object2.beta;
            app.r_x.Value=app.init_object1.r_x;     app.r_y.Value=app.init_object2.r_y;
            app.p_x.Value=app.init_object1.p_x;     app.p_y.Value=app.init_object2.p_y;
            app.S_x.Value=app.init_object1.S_x;     app.S_y.Value=app.init_object2.S_y;
            app.fight_loss1=0; app.fight_loss2=0;
            app.add_num1=0; app.add_num2=0;
        end
    end
%*****************************************************************     

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, init_object1, init_object2)
            % 函数说明：控制界面初始函数
            app.init_object1=init_object1; app.init_object2=init_object2; % 传入参数
            init_interface(app);                                          % 初始化控制界面
            restore_parameter(app);                                       % 显示参数
        end

        % Button pushed function: create_simulate
        function create_simulatePushed(app, event)
            % 回调说明：创建模拟APP
            app.simulate=simulate(app,app.init_object1,app.init_object2);
            app.create_simulate.Enable='off';
            app.create_simulate.Text=[app.fight_type.Value,'模拟中'];
        end

        % Close request function: APPUIFigure
        function APPUIFigureCloseRequest(app, event)
            % 回调说明：关闭控制界面和模拟界面
            delete(app.simulate);
            delete(app)         
        end

        % Value changed function: fight_type
        function fight_typeValueChanged(app, event)
            value = app.fight_type.Value;
            if length(app.simulate)==1
                switch value
                    case '正规战'
                        app.simulate.fight_type='正规战';
                    case '游击战'
                        app.simulate.fight_type='游击战';
                    case '混合战Ⅰ'
                        app.simulate.fight_type='混合战Ⅰ';
                    case '混合战Ⅱ'
                        app.simulate.fight_type='混合战Ⅱ';
                end
                app.create_simulate.Text=[value,'模拟中'];
            end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.APPUIFigure = uifigure('Visible', 'off');
            app.APPUIFigure.Name = '主控制台 APP';
            app.APPUIFigure.CloseRequestFcn = createCallbackFcn(app, @APPUIFigureCloseRequest, true);
            app.formal_fight = uiaxes(app.APPUIFigure);
            title(app.formal_fight, '正规战')
            zlabel(app.formal_fight, 'Z')
            app.formal_fight.Visible = 'off';
            app.guerralli_fight = uiaxes(app.APPUIFigure);
            title(app.guerralli_fight, '游击战 ')
            zlabel(app.guerralli_fight, 'Z')
            app.guerralli_fight.Visible = 'off';
            app.mixed_fight = uiaxes(app.APPUIFigure);
            title(app.mixed_fight, '混合战')
            zlabel(app.mixed_fight, 'Z')
            app.mixed_fight.Visible = 'off';
            app.Label = uilabel(app.APPUIFigure);
            app.Label.Text = '红 蓝 军 作 战 模 拟 与 观 测 系 统';
            app.Label_2 = uilabel(app.APPUIFigure);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = '';
            app.TabGroup = uitabgroup(app.APPUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '蓝军';
            app.xLabel = uilabel(app.Tab);
            app.xLabel.Interpreter = 'latex';
            app.xLabel.Text = '初始兵力x/人';
            app.x = uieditfield(app.Tab, 'numeric');
            app.x.Limits = [50 Inf];
            app.x.ValueDisplayFormat = '%.0f';
            app.x.Value = 50;
            app.alphaLabel = uilabel(app.Tab);
            app.alphaLabel.Interpreter = 'latex';
            app.alphaLabel.Text = {'非战斗损失率α'; ''};
            app.alpha = uieditfield(app.Tab, 'numeric');
            app.alpha.Limits = [0 1];
            app.r_xLabel = uilabel(app.Tab);
            app.r_xLabel.Interpreter = 'latex';
            app.r_xLabel.Text = '射击率$\rm{r_x}$';
            app.r_x = uieditfield(app.Tab, 'numeric');
            app.r_x.Limits = [0 1];
            app.rmp_xEditFieldLabel = uilabel(app.Tab);
            app.rmp_xEditFieldLabel.Interpreter = 'latex';
            app.rmp_xEditFieldLabel.Text = '命中率$\rm{p_x}$';
            app.p_x = uieditfield(app.Tab, 'numeric');
            app.p_x.Limits = [0 1];
            app.m2Label = uilabel(app.Tab);
            app.m2Label.Interpreter = 'latex';
            app.m2Label.Text = {'初始面积$\rm{S_x}$/$\rm{m^2}$'; ''; ''; ''};
            app.S_x = uieditfield(app.Tab, 'numeric');
            app.S_x.Limits = [0 Inf];
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '红军';
            app.alphaLabel_2 = uilabel(app.Tab_2);
            app.alphaLabel_2.Interpreter = 'latex';
            app.alphaLabel_2.Text = {'非战斗损失率β'; ''};
            app.beta = uieditfield(app.Tab_2, 'numeric');
            app.beta.Limits = [0 1];
            app.m2Label_2 = uilabel(app.Tab_2);
            app.m2Label_2.Interpreter = 'latex';
            app.m2Label_2.Text = {'初始面积$\rm{S_y}$/$\rm{m^2}$'; ''; ''; ''};
            app.S_y = uieditfield(app.Tab_2, 'numeric');
            app.S_y.Limits = [0 Inf];
            app.xLabel_2 = uilabel(app.Tab_2);
            app.xLabel_2.Interpreter = 'latex';
            app.xLabel_2.Text = '初始兵力y/人';
            app.y = uieditfield(app.Tab_2, 'numeric');
            app.y.Limits = [50 Inf];
            app.y.ValueDisplayFormat = '%.0f';
            app.y.Value = 50;
            app.r_xLabel_2 = uilabel(app.Tab_2);
            app.r_xLabel_2.Interpreter = 'latex';
            app.r_xLabel_2.Text = '射击率$\rm{r_y}$';
            app.r_y = uieditfield(app.Tab_2, 'numeric');
            app.r_y.Limits = [0 1];
            app.rmp_yLabel = uilabel(app.Tab_2);
            app.rmp_yLabel.Interpreter = 'latex';
            app.rmp_yLabel.Text = '命中率$\rm{p_y}$';
            app.p_y = uieditfield(app.Tab_2, 'numeric');
            app.p_y.Limits = [0 1];
            app.Image = uiimage(app.APPUIFigure);
            app.Image.ImageSource = 'school.jpeg';
            app.Image2 = uiimage(app.APPUIFigure);
            app.Image2.ImageSource = 'school.jpg';
            app.TextArea = uitextarea(app.APPUIFigure);
            app.TextArea.Value = {'实时战况'};
            app.TextArea_2 = uitextarea(app.APPUIFigure);
            app.Label_5 = uilabel(app.APPUIFigure);
            app.Label_5.Text = '当前兵力/人：';
            app.sLabel = uilabel(app.APPUIFigure);
            app.sLabel.Text = {'战斗减员率(人/s)：'; ''};
            app.Label_7 = uilabel(app.APPUIFigure);
            app.Label_7.Text = '蓝军';
            app.Label_8 = uilabel(app.APPUIFigure);
            app.Label_8.Text = '红军';
            app.sLabel_2 = uilabel(app.APPUIFigure);
            app.sLabel_2.Text = '增援率(人/s)：';
            app.current_solder1 = uilabel(app.APPUIFigure);
            app.current_solder1.Interpreter = 'latex';
            app.current_solder1.Text = '';
            app.fight_loss_rate1 = uilabel(app.APPUIFigure);
            app.fight_loss_rate1.Interpreter = 'latex';
            app.fight_loss_rate1.Text = '';
            app.reinforce_rate1 = uilabel(app.APPUIFigure);
            app.reinforce_rate1.Interpreter = 'latex';
            app.reinforce_rate1.Text = '';
            app.current_solder2 = uilabel(app.APPUIFigure);
            app.current_solder2.Interpreter = 'latex';
            app.current_solder2.Text = '';
            app.fight_loss_rate2 = uilabel(app.APPUIFigure);
            app.fight_loss_rate2.Interpreter = 'latex';
            app.fight_loss_rate2.Text = '';
            app.reinforce_rate2 = uilabel(app.APPUIFigure);
            app.reinforce_rate2.Interpreter = 'latex';
            app.reinforce_rate2.Text = '';
            app.review = uitextarea(app.APPUIFigure);
            app.review.Editable = 'off';
            app.create_simulate = uibutton(app.APPUIFigure, 'push');
            app.create_simulate.ButtonPushedFcn = createCallbackFcn(app, @create_simulatePushed, true);
            app.create_simulate.Text = '点击创建战场';
            app.report = uitextarea(app.APPUIFigure);
            app.report.Editable = 'off';
            app.result = uitextarea(app.APPUIFigure);
            app.result.Editable = 'off';
            app.result.Value = {'暂无'};
            app.fight_type_label = uilabel(app.APPUIFigure);
            app.fight_type_label.Text = '正规战结果';
            app.maker_information = uitextarea(app.APPUIFigure);
            app.maker_information.Editable = 'off';
            app.maker_information.Value = {'作者：党晨旭'; '班级：自动化2005班'; '学号：U202015091'};
            app.net_loss_rate = uilabel(app.APPUIFigure);
            app.net_loss_rate.Text = '净减员率(人/s)：';
            app.net_loss_rate1 = uilabel(app.APPUIFigure);
            app.net_loss_rate1.Interpreter = 'latex';
            app.net_loss_rate1.Text = '';
            app.net_loss_rate2 = uilabel(app.APPUIFigure);
            app.net_loss_rate2.Interpreter = 'latex';
            app.net_loss_rate2.Text = '';
            app.formal_num = uilabel(app.APPUIFigure);
            app.formal_num.Text = '游击部队数量(人)：';
            app.formal_num1 = uilabel(app.APPUIFigure);
            app.formal_num1.Interpreter = 'latex';
            app.formal_num1.Text = '';
            app.formal_num2 = uilabel(app.APPUIFigure);
            app.formal_num2.Interpreter = 'latex';
            app.formal_num2.Text = '';
            app.simulate_result = uitextarea(app.APPUIFigure);
            app.simulate_result.Value = {'仿真结果'};
            app.Label_9 = uilabel(app.APPUIFigure);
            app.Label_9.Interpreter = 'latex';
            app.Label_9.Text = '战斗类型';
            app.fight_type = uidropdown(app.APPUIFigure);
            app.fight_type.Items = {'正规战', '游击战', '混合战Ⅰ', '混合战Ⅱ'};
            app.fight_type.ValueChangedFcn = createCallbackFcn(app, @fight_typeValueChanged, true);
            app.fight_type.Value = '正规战';
            % Show the figure after all components are created
            app.APPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = control(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.APPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.APPUIFigure)
        end
    end
end