classdef login_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        APPUIFigure      matlab.ui.Figure
        default          matlab.ui.control.Button
        logic_label      matlab.ui.control.Label
        S_label          matlab.ui.control.Label
        p_label          matlab.ui.control.Label
        r_label          matlab.ui.control.Label
        f_label          matlab.ui.control.Label
        x_label          matlab.ui.control.Label
        cover_square     matlab.ui.control.NumericEditField
        p                matlab.ui.control.NumericEditField
        r                matlab.ui.control.NumericEditField
        alpha            matlab.ui.control.NumericEditField
        x                matlab.ui.control.NumericEditField
        clear_parameter  matlab.ui.control.Button
        save_parameter   matlab.ui.control.Button
        fight_type       matlab.ui.control.DropDown
    end

    properties (Access = private)
        init_object1;  %蓝方初始化参数
        init_object2;  %红方初始化参数
    end
    methods (Access = private)
        function  clear_data(app)     
            app.x.Value=50;
            app.alpha.Value=0;
            app.r.Value=0;
            app.p.Value=0;
            app.cover_square.Value=0;
            app.fight_type.Value='正规战';
        end   

        function init_blue_side(app)
        % 创建蓝方参数
            app.init_object1=struct('x',app.x.Value,...
                                'alpha',app.alpha.Value,...
                                'r_x',app.r.Value,...
                                'p_x',app.p.Value,...
                                'S_x',app.cover_square.Value,...
                                'fight_type',app.fight_type.Value);  
            choice = questdlg("确定创建完成吗","完成","YES","NO","YES");
            switch choice
                case "YES"
                    %修改为红军背景
                    u=uiaxes(app.APPUIFigure,'Position',[-20,0,450,600]);
                    u.XTick=[];u.YTick=[];
                    u.Visible='off';
                    cdata=imread('figures/red2.jpg');
                    cdata = insertShape(cdata,'Line',[0 72 500 72],'LineWidth',2,'Color','white');
                    im=image(cdata,'Parent',u);
                    u.XLim=[0 inf];u.YLim=[0 inf];
                    app.save_parameter.BackgroundColor='0 0.45 0.74';
                    app.clear_parameter.BackgroundColor='0 0.45 0.74'; 
                    app.default.BackgroundColor='0 0.45 0.74';
                    %复位输入栏参数
                    clear_data(app);
                    %播放红军音频
                    clear('sound');
                    [y,fs] = audioread('figures/红军军团.mp3');
                    sound(y,fs);
                    [y,fs] = audioread('figures/蓝军军团1.mp3');
                    y=y(1:length(y)/4,:);
                    sound(y/20,fs);
                    app.logic_label.Text(7)='红';
                    return;
                case "NO"
                    return;
            end            
        end
        
        function re=init_red_side(app)
        % 创建红方参数，打开主控制APP
            app.init_object2=struct('y',app.x.Value,...
                                'beta',app.alpha.Value,...
                                'r_y',app.r.Value,...
                                'p_y',app.p.Value,...
                                'S_y',app.cover_square.Value,...
                                'fight_type',app.fight_type.Value);   
            choice = questdlg("确定创建完成吗","完成","YES","NO","YES");
            switch choice
                case "YES" %创建完成回调
                    [y,fs] = audioread('figures\完成.mp3');
                    sound(y,fs);  
                    pause(8);
                    
                    clear("sound");
                    re=1; %打开主控制APP
                case "NO"
                    re=0;
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, object1, object2)
            % 获取当前脚本文件路径，添加路径名，须提前执行，否则报错
            file=mfilename('fullpath');
            [path,name]=fileparts(file);
            file=strrep(file,name,'figures');
            addpath(file);
            
            % 添加背景图片
            u=uiaxes(app.APPUIFigure,'Position',[-20,0,450,600]);
            u.XTick=[];u.YTick=[];
            u.Visible='off';            
            cdata=imread('figures/blue3.jpg');
            cdata = insertShape(cdata,'Line',[0 70 500 70],'LineWidth',2,'Color','white');
            im=image(cdata,'Parent',u);
            u.XLim=[0 inf];u.YLim=[0 inf];
            pause(1);
            
            % 添加初始化音频
            [y,fs] = audioread('figures/蓝军军团.mp3');
            sound(y,fs);
            [y,fs] = audioread('figures/蓝军军团1.mp3');
            y=y(1:length(y)/4,:);
            sound(y/20,fs);
        end

        % Button pushed function: save_parameter
        function save_parameterPushed(app, event)
            if app.logic_label.Text(7)=='蓝'   
                init_blue_side(app);   %创建蓝军参数
            else
                if init_red_side(app)  %创建红军参数
                    control(app.init_object1,app.init_object2); %传入初始化参数打开主控制APP
                    delete(app);
                end
            end
            
        end

        % Button pushed function: clear_parameter
        function clear_parameterPushed(app, event)
            clear_data(app);
        end

        % Button pushed function: default
        function defaultPushed(app, event)
            %系统所给的默认参数
            if app.logic_label.Text(7)=='蓝'
                
                object1.x=1500;
                object1.alpha=0.05;
                object1.r_x=0.8;
                object1.p_x=0.5;
                object1.S_x=500;            
                object1.u=0;
                object1.f=0;
                object1.fight_type='正规战';
                app.init_object1=object1;
                app.x.Value=app.init_object1.x;  
                app.alpha.Value=app.init_object1.alpha;
                app.r.Value=app.init_object1.r_x;
                app.p.Value=app.init_object1.p_x;
                app.cover_square.Value=app.init_object1.S_x;
                app.fight_type.Value=app.init_object1.fight_type;
            else
                object2.y=1400;
                object2.beta=0.06;
                object2.r_y=0.9;
                object2.p_y=0.5;
                object2.S_y=600;            
                object2.v=0;
                object2.g=0;
                object2.fight_type='正规战';          
                app.init_object2=object2;
                app.x.Value=app.init_object2.y;
                app.alpha.Value=app.init_object2.beta;
                app.r.Value=app.init_object2.r_y;
                app.p.Value=app.init_object2.p_y;
                app.cover_square.Value=app.init_object2.S_y;
                app.fight_type.Value=app.init_object2.fight_type;
                
            end
        end

        % Close request function: APPUIFigure
        function APPUIFigureCloseRequest(app, event)
            clear("sound");
            delete(app);            
        end

        % Value changed function: p
        function pValueChanged(app, event)
            value = app.p.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.APPUIFigure = uifigure('Visible', 'off');
            app.APPUIFigure.Name = '登陆界面 APP';
            app.APPUIFigure.CloseRequestFcn = createCallbackFcn(app, @APPUIFigureCloseRequest, true);
            app.fight_type = uidropdown(app.APPUIFigure);
            app.fight_type.Items = {'正规战', '游击战', '混合战'};
            app.fight_type.Value = '正规战';
            app.save_parameter = uibutton(app.APPUIFigure, 'push');
            app.save_parameter.ButtonPushedFcn = createCallbackFcn(app, @save_parameterPushed, true);
            app.save_parameter.Text = '确定';
            app.clear_parameter = uibutton(app.APPUIFigure, 'push');
            app.clear_parameter.ButtonPushedFcn = createCallbackFcn(app, @clear_parameterPushed, true);
            app.clear_parameter.Text = '清除';
            app.x = uieditfield(app.APPUIFigure, 'numeric');
            app.x.Limits = [50 20000];
            app.x.ValueDisplayFormat = '%.0f';
            app.x.Value = 50;
            app.alpha = uieditfield(app.APPUIFigure, 'numeric');
            app.alpha.Limits = [0 1];
            app.r = uieditfield(app.APPUIFigure, 'numeric');
            app.r.Limits = [0 1];
            app.p = uieditfield(app.APPUIFigure, 'numeric');
            app.p.Limits = [0 1];
            app.p.ValueChangedFcn = createCallbackFcn(app, @pValueChanged, true);
            app.cover_square = uieditfield(app.APPUIFigure, 'numeric');
            app.cover_square.Limits = [0 2000];
            app.x_label = uilabel(app.APPUIFigure);
            app.x_label.Text = '初始兵力/人';
            app.f_label = uilabel(app.APPUIFigure);
            app.f_label.Text = '非战斗损失率';
            app.r_label = uilabel(app.APPUIFigure);
            app.r_label.Text = '射击率';
            app.p_label = uilabel(app.APPUIFigure);
            app.p_label.Text = '命中率';
            app.S_label = uilabel(app.APPUIFigure);
            app.S_label.Interpreter = 'html';
            app.S_label.Text = '有效活动面积/m<sup>2</sup>';
            app.logic_label = uilabel(app.APPUIFigure);
            app.logic_label.Text = '请 输 入 蓝 军 参 数';
            app.default = uibutton(app.APPUIFigure, 'push');
            app.default.ButtonPushedFcn = createCallbackFcn(app, @defaultPushed, true);
            app.default.Text = '默认值';
            % Show the figure after all components are created
            app.APPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login_interface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.APPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.APPUIFigure)
        end
    end
end