classdef output_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        APPUIFigure      matlab.ui.Figure
        Image            matlab.ui.control.Image
        exit             matlab.ui.control.Button
        remodel          matlab.ui.control.Button
        EditField        matlab.ui.control.EditField
        mixed_fight      matlab.ui.control.UIAxes
        guerralli_fight  matlab.ui.control.UIAxes
        formal_fight     matlab.ui.control.UIAxes
        mixed_fight2     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainapp % 记录传入的app变量名称(此处为控制界面)
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, h1, h2)
        % 函数说明：启动输出界面，将四幅折线图绘到输出界面中
        % 变量说明：main_app:传入的app变量名称(此处为控制界面); h1:蓝方折现图； h2:红方折线图 
            app.mainapp=main_app;
            copyobj(main_app.formal_fight.Children,app.formal_fight);
            copyobj(main_app.guerralli_fight.Children,app.guerralli_fight);
            copyobj(main_app.mixed_fight.Children,app.mixed_fight);
            copyobj([h1,h2],app.mixed_fight2);
         
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: remodel
        function remodelButtonPushed(app, event)

            control(app.mainapp.init_object1,app.mainapp.init_object2);
            
            delete(app);

        end

        % Button pushed function: exit
        function exitButtonPushed(app, event)
            delete(app);
        end

        % Close request function: APPUIFigure
        function APPUIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.APPUIFigure = uifigure('Visible', 'off');
            app.APPUIFigure.Name = '输出界面APP';
            app.APPUIFigure.CloseRequestFcn = createCallbackFcn(app, @APPUIFigureCloseRequest, true);
            app.mixed_fight2 = uiaxes(app.APPUIFigure);
            title(app.mixed_fight2, '混合战Ⅱ')
            zlabel(app.mixed_fight2, 'Z')
            app.formal_fight = uiaxes(app.APPUIFigure);
            title(app.formal_fight, '正规战')
            zlabel(app.formal_fight, 'Z')
            app.guerralli_fight = uiaxes(app.APPUIFigure);
            title(app.guerralli_fight, '游击战')
            zlabel(app.guerralli_fight, 'Z')
            app.mixed_fight = uiaxes(app.APPUIFigure);
            title(app.mixed_fight, '混合战Ⅰ')
            zlabel(app.mixed_fight, 'Z')
            app.EditField = uieditfield(app.APPUIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = '战斗结束，仿真结果已生成';
            app.remodel = uibutton(app.APPUIFigure, 'push');
            app.remodel.ButtonPushedFcn = createCallbackFcn(app, @remodelButtonPushed, true);
            app.remodel.Text = '点击重新模拟';
            app.exit = uibutton(app.APPUIFigure, 'push');
            app.exit.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exit.Text = '点击退出';
            app.Image = uiimage(app.APPUIFigure);
            app.Image.ImageSource = 'walfare.jpg';
            % Show the figure after all components are created
            app.APPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = output_interface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.APPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.APPUIFigure)
        end
    end
end