classdef simulate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        accelerate_label  matlab.ui.control.Label
        accelerate        matlab.ui.control.Slider
        Image             matlab.ui.control.Image
        pause_button      matlab.ui.control.Switch
        Lamp              matlab.ui.control.Lamp
        begin_simulate    matlab.ui.control.StateButton
        UITable           matlab.ui.control.Table
        scatter_graph     matlab.ui.control.UIAxes
        curve_graph       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        flag;       % 判断循环的变量
        control;    % 父方app变量名(此处为control)
        
  
    end
    
    properties (Access = public)
        fight_type; % 全局战斗类型
    end

    methods (Access = public)
% ************************************************************************        
        function update_indexs(app,t)
        % 函数说明：核心计算代码，计算每轮交战后双方的参数变化情况
        % 参数说明：t:仿真时间
            step=0.1*app.accelerate.Value; % 定义迭代步长，用于加速仿真
            global object1 object2
            
            x=object1.x;             y=object2.y;
            alpha=object1.alpha;     beta=object2.beta;
            r_x=object1.r_x;         r_y=object2.r_y;
            p_x=object1.p_x;         p_y=object2.p_y;
            S_x=object1.S_x;         S_y=object2.S_y;   
            x0=object1.x0;           y0=object2.y0;
            x1=object1.x1;           y1=object2.y1;
            x2=object1.x2;           y2=object2.y2;
            F=object1.F;             G=object2.G;
            k1=object1.k1;           k2=object2.k2;
            
            %计算增援率
            t0=100-abs(100-t);
            S0=2000;
            u=max(0,0.005*(exp(0.01*t0)-exp(-0.01*t0))/(exp(0.01*t0)+exp(-0.01*t0))*x0);
            v=max(0,0.005*(exp(0.01*t0)-exp(-0.01*t0))/(exp(0.01*t0)+exp(-0.01*t0))*y0);
            % 正规战计算战斗减员率
            if  strcmp(app.fight_type,'正规战')
                f=y*r_y*p_y;
                g=x*r_x*p_x;
                F=f+alpha*x-u;                
                G=g+beta*y-v;

            % 游击战计算战斗减员率
            elseif  strcmp(app.fight_type,'游击战')
                f=0.2*x*y*r_y*S0/(x0+y0)/S_x;
                g=0.2*x*y*r_x*S0/(x0+y0)/S_y;
                F=f+alpha*x-u;                
                G=g+beta*y-v;
            
            % 混合战计算战斗减员率
            elseif strcmp(app.fight_type,'混合战Ⅰ')
                if x1>5
                    f1=y*r_y*p_y;
                    f2=0.2*x2*y*r_y*(1-p_y)*S0/(S_x*(x0+y0));

                    F1=-k1*u+f1+k1*alpha*x;
                    F2=-(1-k1)*u+f2+(1-k1)*alpha*x;
                    
                    f=f1+f2;
                    F=(x1-max(x1-step*F1,5))/step+(x2-max(x2-step*F2,5))/step;                                       
                    
                    x1=max(x1-step*F1,5);                    
                    x2=max(x2-step*F2,5);
                else
                    f=0.2*x*y*r_y*S0/(S_x*(x0+y0));                
                    F=f+alpha*x-u;
                    x2=x-step*F;
                end
                if y1>5
                    g1=x*r_x*p_x;
    
                    g2=0.2*y2*x*r_x*(1-p_x)*S0/(S_y*(x0+y0));
                    g=g1+g2;
                    
                    G1=-k2*v+g1+k2*beta*y;
                    G2=-(1-k2)*v+g2+(1-k2)*beta*y;
                    G=(y1-max(y1-step*G1,5))/step+(y2-max(y2-step*G2,5))/step;                     
                    
                    y1=max(y1-step*G1,5);                    
                    y2=max(y2-step*G2,5);
                else
    
                    g=0.2*x*y*r_x*S0/(S_y*(x0+y0));  
                    G=g+beta*y-v; 
                    y2=y-step*G;
                end
            else
                f=y*r_y*p_y;

                g=0.2*x*y*r_x*S0/(x0+y0)/S_y;

                F=f+alpha*x-u;
                G=g+beta*y-v;
            end
            x=x-step*F;
            y=y-step*G;
            % 更新状态            
            object1.x=max(x,5);    object2.y=max(y,5);
            object1.alpha=alpha;   object2.beta=beta;
            object1.r_x=r_x;       object2.r_y=r_y;
            object1.p_x=p_x;       object2.p_y=p_y;
            object1.S_x=S_x;       object2.S_y=S_y;
            object1.u=u;           object2.v=v;
            object1.f=f;           object2.g=g;
            object1.F=F;           object2.G=G;
            object1.x1=max(x1,5);  object1.x2=max(x2,5);
            object2.y1=max(y1,5);  object2.y2=max(y2,5);
        end
    end
 % ************************************************************************   
    methods (Access = private)
        
        function pre_prepare(app)
        % 函数说明：在正式开始模拟之前对模拟界面进行初始化
        % 变量说明：无
            cla(app.curve_graph);
            cla(app.scatter_graph);
            app.fight_type=app.control.fight_type.Value;
            app.curve_graph.XLim=[0,100];
            app.curve_graph.XTick=[0,20,40,60,80,100];
            app.control.fight_type.Editable='off';
            app.begin_simulate.Enable='off';  %关闭模拟控件,仿真期间无法修改参数
     
            app.accelerate.Value=1;
            restore_parameter(app.control);
            app.control.fight_type.Enable='off';
            % 仿真类型播报
            switch app.fight_type
                case '正规战'
                    [y,fs] = audioread('figures\正规战.mp3');
                case '游击战'
                    [y,fs] = audioread('figures\游击战.mp3');
                case '混合战Ⅰ'
                    [y,fs] = audioread('figures\混合战.mp3');
                    app.control.formal_num.Visible='on';
                    app.control.formal_num1.Visible='on';
                    app.control.formal_num2.Visible='on';
                case '混合战Ⅱ'     
                    [y,fs] = audioread('figures\混合战1.mp3');
                    app.control.formal_num.Visible='off';
                    app.control.formal_num1.Visible='off';
                    app.control.formal_num2.Visible='off';
            end
            sound(y,fs);
            app.Lamp.Color='g';
            app.pause_button.Visible='on';
            pause(1.5);

        end
% ************************************************************************
        function update_curve_graph(app,h1,h2,t)
        % 函数说明：更新模拟界面折线图
        % 参数说明：h1:方折线图；
        %           h2:红方折线图；
        %           t:仿真时间
            global object1 object2;
            if t>=app.curve_graph.XLim(2)
                app.curve_graph.XLim(2)=t+100;
                app.curve_graph.XTick=[0,20,40,60,80,100]*...
                (round(app.curve_graph.XLim(2)/100+1));
            end
            x= object1.x;   y= object2.y;
            addpoints(h1,t,x);
            addpoints(h2,t,y);
            drawnow;         
        end
% ************************************************************************        
        function update_scatter_graph (app)
        % 函数说明：更新散点图  
            global object1 object2;
            x=round(object1.x);
            y=round(object2.y);
            pointx_1=randn([1,x])*10+35;
            pointy_1=randn([1,x])*10+35;
            pointx_2=randn([1,y])*10+65;
            pointy_2=randn([1,y])*10+65;

            cla(app.scatter_graph);
            scatter(app.scatter_graph,pointx_2,pointy_2,'.','red');
            hold(app.scatter_graph,'on');
            scatter(app.scatter_graph,pointx_1,pointy_1,'.','blue');  
        end
        
        function update_table(app,T,t)
        % 函数说明：更新变量表
        % 参数说明：T:变量表；
        %           t:仿真时间
            global object1 object2;
            T.solder_num=[round(object1.x);round(object2.y)];
            T.loss=[ object1.u; object2.v];
            T.hit_rate=[ object1.F; object2.G];
            T.time=[num2str(0.1*t,'%.1f');num2str(0.1*t,'%.1f')];            
            app.UITable.Data =T ;
            s = uistyle('HorizontalAlignment','center');
            addStyle(app.UITable,s,'table','');
            
        end

    end
% ************************************************************************

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, init_object1, init_object2)
        % 函数说明：模拟界面初始化
        % 变量说明：main_app:父级app变量
        %           init_object1:蓝方初始化参数 
        %           init_object2:红方初始化参数
            
            % 背景图片与背景音频
            u=uiaxes(app.UIFigure,'Position',[0,0,750,480]);
            u.XTick=[];u.YTick=[];
            app.curve_graph.YLim =[0,max(init_object1.x,init_object2.y)];
            cdata=imread('figures/fight_bottom1.png');
            im=image(cdata,'Parent',u);
            u.XLim=[0 inf];u.YLim=[0 inf];
            u.Visible='on';
            pause(1.5);
            
            [y,Fs] = audioread("figures/14203.wav");
            clear("sound");
            sound(y,Fs);
            pause(4);
            delete(u);
            % 显示控件
            app.UITable.Visible='on';
            app.begin_simulate.Visible='on';
            app.Image.Visible='on';
            app.Lamp.Visible='on';
            app.accelerate.Visible='on';
            app.accelerate_label.Visible='on';
            
            %显示初始化参数
            app.control=main_app;
            update_dynamic(app.control,app.accelerate.Value);                        
        end

        % Value changed function: begin_simulate
        function begin_simulateValueChanged(app, event)
        %函数说明：整个仿真软件的核心参数,循环模拟双方人数变化，并返回参数到控制界面输出，随时观测核心变量值  
            pre_prepare(app);  % 正式模拟之前初始化界面
            
            global object1 object2
            t=0;  
            app.flag=1;   % 暂停循环的标志变量         
            
            %初始化仿真折线图
            
            app.curve_graph.YLim(2)=max(object1.x,object2.y);
            h1=animatedline(app.curve_graph,'Color','0,0.45,0.74');
            h2=animatedline(app.curve_graph,'Color','0.85,0.33,0.1');
            
            addpoints(h1,t,object1.x);
            addpoints(h2,t,object2.y);
            drawnow;
            
            % 初始化仿真散点图

            pointx1=randn([1,object1.x])*15+35;
            pointy1=randn([1,object1.x])*15+35;
            pointx2=randn([1,object2.y])*15+65;
            pointy2=randn([1,object2.y])*15+65;
            scatter(app.scatter_graph,pointx1,pointy1,'.','blue');
            hold(app.scatter_graph,'on');
            scatter(app.scatter_graph,pointx2,pointy2,'.','red');
            
            
            % 创建数据表
            T=table;
            T.fighters={'蓝军';'红军'};
            
            % 开始仿真，为防止人数变为负数，我们认为小于5时即为0
            while  object1.x>5 &&  object2.y>5   
                %pause(0.5)
                if app.flag==0
                    pause(0.1);
                    continue;
                end
                t=t+round(app.accelerate.Value);  % 加快仿真速度
                update_curve_graph(app,h1,h2,t);  % 更新折线图  
                update_indexs(app,t);  % 核心算法，计算每轮战斗参数变化               
                % update_scatter_graph(app,pointx1(1:object1.x),pointx2(1:object2.y),pointy1(1:object1.x),pointy2(1:object2.y));  %更新散点图               
                if mod(t,10)==0
                    update_scatter_graph(app);  %每隔十轮更新散点图
                end
                update_dynamic(app.control,app.accelerate.Value); % 更新控制界面
                update_table(app,T,t); %更新变量显示表                
            end
            update_scatter_graph(app);  %绘制最后散点图散点图
            % 一轮模拟结束,判断是否结束所有仿真或者继续模拟
            if strcmp(app.fight_type,'混合战Ⅱ')==0
                app.begin_simulate.Enable='on';
            else
                %app.begin_simulate.Enable='on';
                app.begin_simulate.Text='模拟结束';
            end

            % 控制界面输出本轮仿真报告
            update_legend(app.control,app.fight_type,t);
            
            % 将仿真曲线绘制到控制界面
            output(app.control,h1,h2,app.fight_type);
        end

        % Value changed function: pause_button
        function pause_buttonValueChanged(app, event)
            % 函数说明：控制仿真暂停与继续
            switch app.pause_button.Value 
                case '继续'   
                    [y,fs] = audioread('figures\战斗继续.mp3');
                    sound(y,fs);
                    app.Lamp.Color='g';
                    app.flag=1;                   
                case '暂停'
                    app.Lamp.Color='r';
                    app.flag=0;
                    [y,fs] = audioread('figures\仿真暂停.mp3');
                    sound(y,fs);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 关闭当前界面
            clear("sound");
            delete(app);            
        end

        % Value changed function: accelerate
        function accelerateValueChanged(app, event)
            app.accelerate.Value=round(app.accelerate.Value)
        end

        % Value changing function: accelerate
        function accelerateValueChanging(app, event)
            app.accelerate.Value=round(app.accelerate.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '实时模拟 APP';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.curve_graph = uiaxes(app.UIFigure);
            title(app.curve_graph, '剩余兵力')
            app.curve_graph.XLim = [0 100];
            app.curve_graph.YLim = [0 15000];
            app.curve_graph.XTick = [0 20 40 60 80 100];
            app.curve_graph.YAxisLocation = 'origin';
            app.scatter_graph = uiaxes(app.UIFigure);
            title(app.scatter_graph, '形势模拟')
            app.scatter_graph.XLim = [0 100];
            app.scatter_graph.YLim = [0 100];
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'作战双方'; '剩余人数'; '增援率'; '损失率'; '时间'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false true false];
            app.UITable.ColumnEditable = [true false true false];
            app.UITable.Visible = 'off';
            app.begin_simulate = uibutton(app.UIFigure, 'state');
            app.begin_simulate.ValueChangedFcn = createCallbackFcn(app, @begin_simulateValueChanged, true);
            app.begin_simulate.Visible = 'off';
            app.begin_simulate.Text = '开始模拟';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Visible = 'off';
            app.pause_button = uiswitch(app.UIFigure, 'slider');
            app.pause_button.Items = {'暂停', '继续'};
            app.pause_button.ValueChangedFcn = createCallbackFcn(app, @pause_buttonValueChanged, true);
            app.pause_button.Visible = 'off';
            app.pause_button.Value = '继续';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'head1.jpeg';
            app.accelerate = uislider(app.UIFigure);
            app.accelerate.Limits = [1 10];
            app.accelerate.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.accelerate.ValueChangedFcn = createCallbackFcn(app, @accelerateValueChanged, true);
            app.accelerate.ValueChangingFcn = createCallbackFcn(app, @accelerateValueChanging, true);
            app.accelerate.Visible = 'off';
            app.accelerate.Value = 1;
            app.accelerate_label = uilabel(app.UIFigure);
            app.accelerate_label.Visible = 'off';
            app.accelerate_label.Text = '加速仿真';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulate(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end