classdef simulink < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.StateButton
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        object1;
        

        object2;
    end
    
    methods (Access = public)
        
        function results = func(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, object1, object2)
            
            object1.solder_num=12000;
            object1.unfight_decrease_rate=0.1;
            object1.shoot_rate=0.08;
            object1.hit_rate=0.12;
            object1.cover_square=500;
            object1.fight_type='1';

            object2.solder_num=10000;
            object2.unfight_decrease_rate=0.12;
            object2.shoot_rate=0.1;
            object2.hit_rate=0.1;
            object2.cover_square=600;
            object2.fight_type='2';

            app.object1=object1;
            app.object2=object2;
            

        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            %object1=app.object1;object2=app.object2;
            num=[app.object1.solder_num;app.object2.solder_num];
            t=0;
            while app.object1.solder_num>=10 && app.object2.solder_num>=10
                t=t+1;
                [app.object1.solder_num,app.object2.solder_num]=update_solder_num(app.object1,app.object2,t);
                num=[num,[app.object1.solder_num;app.object2.solder_num]];
                h=plot(app.UIAxes,linspace(1,length(num),length(num)),num);
                legend(h,'甲方','乙方');
                
                pause(0.1);
                fprintf('-------------\n');
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '开始模拟';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulink(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end